classdef Results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BacktestDateDatePicker       matlab.ui.control.DatePicker
        BacktestDateDatePickerLabel  matlab.ui.control.Label
        PortfolioOptimizerLabel      matlab.ui.control.Label
        AllocationData               matlab.ui.control.Table
        PortfolioData                matlab.ui.control.Table
        RunBacktestButton            matlab.ui.control.Button
        OptimalAllocationAxes        matlab.ui.control.UIAxes
        OriginalAllocationAxes       matlab.ui.control.UIAxes
        ReturnsAxes                  matlab.ui.control.UIAxes
        PortfolioPrice               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunBacktestButton
        function OptimizePortfolio(app, event)
% Read allocations from the table
inputs = readtable('Portfolio.xlsx');

% Load Parameters
portfolio_tickers = inputs.Tickers;
original_weights = inputs.Weights;
lower_bounds = inputs.LowerBound;
upper_bounds = inputs.UpperBound;

% If weights do not add up to 1, normalize them
if sum(original_weights) ~= 1
    warning("Provided weights did not add up to 1.0 and had to be re-normalized.")
    original_weights = original_weights / sum(original_weights);
end

% Re-standardize weights in case they do not add up to 100%

% Read market data file previously generated
% TODO: If there is no market data it or there is that option it should
% re-fetch it, otherwise no need to keep running the function over and 
% over again...
RetrieveMarketData(app.BacktestDateDatePicker.Value)
market_data = readtimetable('market_data.csv', 'VariableNamingRule', 'preserve');
dates = market_data.Time;

% Obtain daily returns and split between portfolio holding returns and
% market
daily_return_full = tick2ret(market_data{:, 1:end});
daily_return = daily_return_full(:, 1:end-1);
market_return = daily_return_full(:, end);


% Construct Portfolio
p = Portfolio('AssetList', portfolio_tickers);

% Estimate Portfolio Moments
p = estimateAssetMoments(p, daily_return);

% Set Portfolio Constraints, Long Only, 100% allocation at all times
p = setDefaultConstraints(p);

p = setBounds(p, lower_bounds, upper_bounds);

% Estimate Optimal Weights for Sharpe Ratio
% TODO: You should be able to select what you wish to optimize for 
optimal_weights = estimateMaxSharpeRatio(p);

% Calculate moments. Not sure what is wrong with these though....
% [risk_original, ret_original] = estimatePortMoments(p, original_weights);
% [risk_optimal, ret_optimal] = estimatePortMoments(p, optimal_weights);
% 
% sharpe_original = estimatePortMoments(p, original_weights);
% sharpe_optimal = estimatePortMoments(p, optimal_weights);

% Portfolio returns & Price
portfolio_returns_original = daily_return * original_weights;
portfolio_returns_optimal  = daily_return * optimal_weights;

% Integrate to calculate price of each portfolio
portfolio_value_original = 1e4 * exp(cumsum([0; portfolio_returns_original]));
portfolio_value_optimal  = 1e4 * exp(cumsum([0; portfolio_returns_optimal]));


portfolio_returns_tt = timetable(dates(2:end), portfolio_returns_original, portfolio_returns_optimal);

% Visualizations: 
% Pie and chart of the original portfolio

% Create pie charts
pie(app.OriginalAllocationAxes, original_weights);
pie(app.OptimalAllocationAxes, optimal_weights);

legend(app.OptimalAllocationAxes, portfolio_tickers);


% Portfolio prices
semilogy(app.PortfolioPrice, dates, portfolio_value_original,'DisplayName','Provided Portfolio')
hold(app.PortfolioPrice, "on");
semilogy(app.PortfolioPrice, dates, portfolio_value_optimal,'DisplayName','Optimal Portfolio')
xlabel("Date");
ylabel("Portfolio Value");
legend(app.PortfolioPrice, {'Original Portfolio' 'Optimal Portfolio'}, "Location", "northwest");
hold(app.PortfolioPrice, "off")


% Security specific information
t = table(portfolio_tickers, original_weights, optimal_weights, lower_bounds, upper_bounds, ...
    'VariableNames', {'Tickers', 'Original Weights', 'Optimal Weights', 'Lower Bounds', 'Upper Bounds'});

app.AllocationData.Data = t;
app.AllocationData.ColumnName = {'Tickers', 'Provided w', 'Optimal w', 'Lower Bound', 'Upper Bound'};


% Portfolio specific charts
% Calculations
annualized_portfolio_returns = retime(portfolio_returns_tt, "yearly","sum");
annualized_portfolio_returns.portfolio_returns_original = 100*annualized_portfolio_returns.portfolio_returns_original;
annualized_portfolio_returns.portfolio_returns_optimal = 100*annualized_portfolio_returns.portfolio_returns_optimal;
original_beta = corrcoef(portfolio_returns_original, market_return);
optimal_beta  = corrcoef(portfolio_returns_optimal, market_return);


% TODO: We shouldn't assume that the risk-less rate is zero
annualized_sharpe_original = sqrt(252) * sharpe(portfolio_returns_original, 0);
annualized_sharpe_optimal  = sqrt(252) * sharpe(portfolio_returns_optimal, 0);

% Outputs 
start_balance = [portfolio_value_original(1) portfolio_value_optimal(1)];
end_balance = [portfolio_value_original(end) portfolio_value_optimal(end)];
annualized_return = [mean(annualized_portfolio_returns.portfolio_returns_original) mean(annualized_portfolio_returns.portfolio_returns_optimal)];
standard_deviation = [std(annualized_portfolio_returns.portfolio_returns_original) std(annualized_portfolio_returns.portfolio_returns_optimal)];
best_year = [max(annualized_portfolio_returns.portfolio_returns_original) max(annualized_portfolio_returns.portfolio_returns_optimal)];
worst_year = [min(annualized_portfolio_returns.portfolio_returns_original) min(annualized_portfolio_returns.portfolio_returns_optimal)];
max_drawdown = [(-1)*maxdrawdown(portfolio_value_original)*100 (-1)*maxdrawdown(portfolio_value_optimal)*100];
sharpe_ratio = [annualized_sharpe_original annualized_sharpe_optimal];
betas = [original_beta(2, 1), optimal_beta(2, 1)];

% Final Table 
data = [start_balance; end_balance; annualized_return; standard_deviation; 
    best_year; worst_year; max_drawdown; sharpe_ratio; betas];

results = array2table(data, 'VariableNames', {'Provided Portfolio', 'Optimal Portfolio'}, ...
    'RowNames', {'Start Balance', 'End Balance', 'CAGR', 'Standard Deviation', 'Best Year' ...
    'Worst Year', 'Max Drawdown', 'Sharpe Ratio', 'Beta'});


%guidata(app.PortfolioData, results);
app.PortfolioData.Data = results;
app.PortfolioData.RowName = {'Start Balance', 'End Balance', 'CAGR', 'Standard Deviation', 'Best Year' ...
    'Worst Year', 'Max Drawdown', 'Sharpe Ratio', 'Beta'};

% Create returns for every year
bar(app.ReturnsAxes, annualized_portfolio_returns.Time, [annualized_portfolio_returns.portfolio_returns_original, ...
    annualized_portfolio_returns.portfolio_returns_optimal] ...
    );

ytickformat('percentage');

legend(app.ReturnsAxes, 'Provided Portfolio', 'Optimal Portfolio');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PortfolioPrice = uiaxes(app.UIFigure);
            title(app.PortfolioPrice, 'Portfolio Price')
            xlabel(app.PortfolioPrice, 'Date')
            ylabel(app.PortfolioPrice, 'Value')
            zlabel(app.PortfolioPrice, 'Z')
            app.ReturnsAxes = uiaxes(app.UIFigure);
            title(app.ReturnsAxes, 'Returns Breakdown')
            xlabel(app.ReturnsAxes, 'Year')
            ylabel(app.ReturnsAxes, 'Returns (%)')
            zlabel(app.ReturnsAxes, 'Z')
            app.OriginalAllocationAxes = uiaxes(app.UIFigure);
            title(app.OriginalAllocationAxes, 'Provided Allocation')
            zlabel(app.OriginalAllocationAxes, 'Z')
            app.OptimalAllocationAxes = uiaxes(app.UIFigure);
            title(app.OptimalAllocationAxes, 'Optimal Allocation')
            zlabel(app.OptimalAllocationAxes, 'Z')
            app.RunBacktestButton = uibutton(app.UIFigure, 'push');
            app.RunBacktestButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizePortfolio, true);
            app.RunBacktestButton.Text = 'Run Backtest';
            app.PortfolioData = uitable(app.UIFigure);
            app.PortfolioData.ColumnName = {'Provided Portfolio'; 'Optimal Portfolio'};
            app.PortfolioData.RowName = {};
            app.AllocationData = uitable(app.UIFigure);
            app.AllocationData.ColumnName = {'Provided Portfolio'; 'Optimal Portfolio'};
            app.AllocationData.RowName = {};
            app.PortfolioOptimizerLabel = uilabel(app.UIFigure);
            app.PortfolioOptimizerLabel.Text = 'Portfolio Optimizer';
            app.BacktestDateDatePickerLabel = uilabel(app.UIFigure);
            app.BacktestDateDatePickerLabel.Text = {'Backtest Date'; ''};
            app.BacktestDateDatePicker = uidatepicker(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Results

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end